classdef LiftAppFINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InsideElevatorPanel            matlab.ui.container.Panel
        LiftDisplay                    matlab.ui.control.EditField
        LiftDirection                  matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        GButton                        matlab.ui.control.Button
        OpenDoors                      matlab.ui.control.Button
        CloseDoors                     matlab.ui.control.Button
        Alarm                          matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        OutsideElevatorPanel           matlab.ui.container.Panel
        CallLiftButton                 matlab.ui.control.Button
        FloorLiftIsAt                  matlab.ui.control.EditField
        FloorEditFieldLabel            matlab.ui.control.Label
        FloorLiftIsCalledFrom          matlab.ui.control.EditField
        MaintenanceButton              matlab.ui.control.StateButton
        Obstruction_SensorButtonGroup  matlab.ui.container.ButtonGroup
        No_ObstructionButton           matlab.ui.control.RadioButton
        ObstructionButton              matlab.ui.control.RadioButton
        ObstructLamp                   matlab.ui.control.Lamp
        Weight_SensorButtonGroup       matlab.ui.container.ButtonGroup
        kgButton                       matlab.ui.control.RadioButton
        kgButton_2                     matlab.ui.control.RadioButton
        kgButton_3                     matlab.ui.control.RadioButton
        kgButton_4                     matlab.ui.control.RadioButton
        WeightWarning                  matlab.ui.control.Lamp
        Smoke_DetectorButtonGroup      matlab.ui.container.ButtonGroup
        NosmokeButton                  matlab.ui.control.RadioButton
        SmokeButton                    matlab.ui.control.RadioButton
        FireAlarms                     matlab.ui.control.Lamp
        RESETButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        FloorNow = 0;
        FloorToGoTo = 0;
         % Description
        whereToGo = "";
        LiftMusicPlaying = false;
        IsMoving = true;
        FloorNameCalled = false;
        previousFloor = nan;
        FilenameLiftGoingUp ="/Users/eric/MATLAB-Drive/SystemsLiftProject/LiftGoingUp.mp3";
        FilenameLiftGoingDown ="/Users/eric/MATLAB-Drive/SystemsLiftProject/LiftGoingDown.mp3";
        FilenameLiftDoorsOpening ="/Users/eric/MATLAB-Drive/SystemsLiftProject/DoorsOpening.mp3";
        FilenameLiftDoorsClosing ="/Users/eric/MATLAB-Drive/SystemsLiftProject/DoorsClosing.mp3";
        FilenameLiftMusic ="/Users/eric/MATLAB-Drive/SystemsLiftProject/liftmusic.mp3";
        FilenameGroundFloor = "/Users/eric/MATLAB-Drive/SystemsLiftProject/GroundFloor.mp3";
        FilenameFirstFloor ="/Users/eric/MATLAB-Drive/SystemsLiftProject/FirstFloor.mp3";
        FilenameSecondFloor ="/Users/eric/MATLAB-Drive/SystemsLiftProject/SecondFloor.mp3";
        FilenameThirdFloor = "/Users/eric/MATLAB-Drive/SystemsLiftProject/ThirdFloor.mp3";
        FilenameFourthFloor ="/Users/eric/MATLAB-Drive/SystemsLiftProject/FourthFloor.mp3";
        FilenameFifthFloor = "/Users/eric/MATLAB-Drive/SystemsLiftProject/FifthFloor.mp3";
        FilenameWeightAlarm = "/Users/eric/MATLAB-Drive/SystemsLiftProject/WeightExceeded.mp3";
        FilenameFireAlarm = "/Users/eric/MATLAB-Drive/SystemsLiftProject/fire5.mp3";


        DoorIsOpened = false;
        WeightDetected = false;
        FireDetected = false;
        ObstructionDected = false;


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button, Button_2, Button_3, 
        % Button_4, Button_5, CallLiftButton, GButton
        function ButtonPushed(app, event)
            if(isempty(app.whereToGo))
                app.whereToGo = "";
            end
            
            if(event.Source.Text == "Call Lift")
                if(app.FloorLiftIsCalledFrom.Value == "G")
                app.whereToGo =  app.whereToGo + "0" + " ";
                else
                app.whereToGo =  app.whereToGo +" "+ app.FloorLiftIsCalledFrom.Value + " ";
                end
            elseif(event.Source.Text == "G")
                app.whereToGo =  app.whereToGo + "0" + " ";
            else
                app.whereToGo =  app.whereToGo +" "+ [event.Source.Text] + " ";
            end
            
            %Fire Alarm
            try
               [y, Fs] = audioread(app.FilenameFireAlarm);
               POFireAlarm=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            %Weight Alarm
            try
               [y, Fs] = audioread(app.FilenameWeightAlarm);
               POWeightAlarm=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            
            %Closing
            try
               [y, Fs] = audioread(app.FilenameLiftDoorsClosing);
               POClosingDoors=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            %Opening
            try
               [y, Fs] = audioread(app.FilenameLiftDoorsOpening);
               POOpeningDoors=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end


            %Floors Calling

            %GroundFloor
            try
               [y, Fs] = audioread(app.FilenameGroundFloor);
               POGround=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            %FirstFloor
            try
               [y, Fs] = audioread(app.FilenameFirstFloor);
               POFirst=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            %SecondFloor
            try
               [y, Fs] = audioread(app.FilenameSecondFloor);
               POSecond=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            %ThirdFloor
            try
               [y, Fs] = audioread(app.FilenameThirdFloor);
               POThird=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            %FourthFloor
            try
               [y, Fs] = audioread(app.FilenameFourthFloor);
               POFourth=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            
            %FifthFloor
            try
               [y, Fs] = audioread(app.FilenameFifthFloor);
               POFifth=audioplayer(y,Fs);
            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
 
            
            try
            app.whereToGo = unique(split(app.whereToGo));
            app.whereToGo = app.whereToGo(2:length(app.whereToGo));
            app.whereToGo = str2double(app.whereToGo);
            [~, I ] = min(abs(app.whereToGo - app.FloorNow));
             app.FloorToGoTo = app.whereToGo(I);
            catch ME
                
            end
            
            load_system('Elevator_Group_Final');
            find_system;
            pause(1)
            if(~isempty(app.FloorToGoTo))
                   set_param('Elevator_Group_Final/requested_floor','Value',num2str(app.FloorToGoTo));
            end

            set_param('Elevator_Group_Final','SimulationCommand','start');           
            
            WeightAlarmCalcIntermediate = get_param('Elevator_Group_Final/Weight_Sensor','Value');
            WeightAlarm = eval(WeightAlarmCalcIntermediate);
            
            if(WeightAlarm > 299)
                playblocking(POWeightAlarm);
                pause(0.3);
                warning("heavy")
            end
            
            FireAlarmCalcIntermediate = get_param('Elevator_Group_Final/Smoke_Detector','Value');
            FireAlarm = eval(FireAlarmCalcIntermediate);
            if(FireAlarm == 1)
                playblocking(POFireAlarm);
                warning("Fire")
                
            end
            
            while(WeightAlarm > 299 || FireAlarm == 1)
                WeightAlarmCalcIntermediate = get_param('Elevator_Group_Final/Weight_Sensor','Value');
                WeightAlarm = eval(WeightAlarmCalcIntermediate);
                pause(0.1)
                FireAlarmCalcIntermediate = get_param('Elevator_Group_Final/Smoke_Detector','Value');
                FireAlarm = eval(FireAlarmCalcIntermediate);
                pause(0.2)
            end
            
            
            if(app.FloorToGoTo>app.FloorNow)
                %Lift going up
                    try
                    [y, Fs] = audioread(app.FilenameLiftGoingUp);
                    PO=audioplayer(y,Fs);
                    % Play audio
                    playblocking(PO);
                    catch ME
                    uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
                    end
                
            elseif(app.FloorToGoTo<app.FloorNow)
                %Lift going down
                    try
                       [y, Fs] = audioread(app.FilenameLiftGoingDown);
                       PO=audioplayer(y,Fs);
                       % Play audio
                       playblocking(PO)
                    catch ME
                      uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
                    end                
            end            
            
            if(~app.LiftMusicPlaying)
                app.LiftMusicPlaying = true;
                %play music 
                try
                   [y, Fs] = audioread(app.FilenameLiftMusic);
                   PO=audioplayer(y,Fs);
                   % Play audio
                   play(PO)
                catch ME
                  uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
                end
            end
            pause(0.25)

            playblocking(POClosingDoors)
            app.IsMoving = true;
            while(~isempty(app.whereToGo))
            
            %Read what floor I am at          
                try
                pause(0.25)
                floorCalcIntermediate = get_param('Elevator_Group_Final/Current_Floor','RuntimeObject');
                floor = floorCalcIntermediate.InputPort(1).Data;
                if(floor ~= app.previousFloor)
                    app.FloorNameCalled = false;
                end
                app.previousFloor = floor;
                if(floor == 0)
                    app.LiftDisplay.Value = "G";
                    app.FloorLiftIsAt.Value = "G";
                else
                    app.LiftDisplay.Value = num2str(floor);
                    app.FloorLiftIsAt.Value = num2str(floor);
                end
                
                app.FloorNow = floor;
                catch ME
                    display(ME)
                    pause(2)
                    warning("not working")
                end
                if(app.FloorToGoTo>9)
                    b = num2str(app.FloorToGoTo) - '0';
                    b = unique(b);
                    app.FloorToGoTo = b(1);
                    b(1) = [];
                    app.whereToGo = unique([b'; app.whereToGo]);
                    clear('b');
                end
                
            if(app.FloorToGoTo>9)
                    b = num2str(app.FloorToGoTo) - '0';
                    b = unique(b);
                    app.FloorToGoTo = b(1);
                    b(1) = [];
                    app.whereToGo = unique([b'; app.whereToGo]);
                    clear('b');
            elseif(app.FloorToGoTo > app.FloorNow)
                app.LiftDirection.Value = "↑";
            elseif(app.FloorToGoTo < app.FloorNow)
                app.LiftDirection.Value = "↓";
            elseif(app.FloorToGoTo == app.FloorNow)
                 if(~isempty(app.whereToGo))
                    app.whereToGo(I) = [];
                    if(~isempty(app.whereToGo))
                    [~, I ] = min(abs(app.whereToGo - app.FloorNow));
                    app.FloorToGoTo = app.whereToGo(I);
                    if(~isempty(app.FloorToGoTo))
                        set_param('Elevator_Group_Final/requested_floor','Value',num2str(app.FloorToGoTo));
                    end
                    end
                 end
                 
                %play "door opening" - use playblocking
                app.IsMoving = false;
                playblocking(POOpeningDoors)
                pause(0.5)
                
                if(~isempty(app.whereToGo))
                playblocking(POClosingDoors);
                end
                
                app.IsMoving = true;
                
                
                % play "door closing" - use playblocking
                %going up
            
                
            else
                error("You should not see this");
            end
            if(app.FloorToGoTo == app.FloorNow)
                app.FloorNameCalled = false;
            end
        if(~app.FloorNameCalled)
            app.FloorNameCalled = true;            
            if(app.FloorNow == 0)
                        app.LiftDisplay.Value = "G";
                        playblocking(POGround);
                    elseif(app.FloorNow == 1)
                        app.LiftDisplay.Value = "1";
                        playblocking(POFirst);
                    elseif(app.FloorNow == 2)
                        app.LiftDisplay.Value = "2";
                        playblocking(POSecond);
                     elseif(app.FloorNow == 3)
                        app.LiftDisplay.Value = "3";
                        playblocking(POThird);
                     elseif(app.FloorNow == 4)
                        app.LiftDisplay.Value = "4";
                        playblocking(POFourth);
                     elseif(app.FloorNow == 5)
                        app.LiftDisplay.Value = "5";
                        playblocking(POFifth);
            else
                warning("the floor to go to is"); warning(num2str(app.FloorToGoTo));
            end
        end
        
            end
            %stop playing lift music --- set lift music to off

stop(PO); app.LiftMusicPlaying = false;
   app.LiftDirection.Value = " ";

   app.IsMoving = false;
   
            
        end

        % Button pushed function: Alarm
        function AlarmButtonPushed(app, event)
            warning("Alarm has been activated");
            %Fire Alarm
            set_param('Elevator_Group_Final/Smoke_Detector','Value',"1");
            try
               [y, Fs] = audioread(app.FilenameFireAlarm);
               POFireAlarm=audioplayer(y,Fs);

            catch ME
              uiwait(msgbox('You need to change the directory to include music- Go to the readme for more information'));
            end
            
            FireAlarmCalcIntermediate = get_param('Elevator_Group_Final/Smoke_Detector','Value');
            FireAlarm = eval(FireAlarmCalcIntermediate);
            if(FireAlarm == 1)
                playblocking(POFireAlarm);
                warning("Fire")
                
            end
            
        end

        % Button pushed function: OpenDoors
        function OpenDoorsButtonPushed(app, event)
                        %display("Open Doors")
            
try
   [y, Fs] = audioread(app.FilenameLiftDoorsOpening);
   PO=audioplayer(y,Fs);
   % Play audio
   playblocking(PO)
catch ME
  display("Open Door music has error")
end
        end

        % Button pushed function: CloseDoors
        function CloseDoorsButtonPushed(app, event)
           %display("Close Doors")
try
   [y, Fs] = audioread(app.FilenameLiftDoorsClosing);
   PO=audioplayer(y,Fs);
   % Play audio
   playblocking(PO)
catch ME
  display("Close Door music has error")
end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Smoke_DetectorButtonGroup.SelectedObject = app.NosmokeButton;
            app.Weight_SensorButtonGroup.SelectedObject = app.kgButton;
            app.Obstruction_SensorButtonGroup.SelectedObject = app.No_ObstructionButton;
            set_param('Elevator_Group_Final/Obstruction_Sensor','Value','0');
            set_param('Elevator_Group_Final/Smoke_Detector','Value','0');
            set_param('Elevator_Group_Final/Weight_Sensor','Value','0');
        end

        % Value changed function: MaintenanceButton
        function MaintenanceButtonValueChanged(app, event)
            value = app.MaintenanceButton.Value;
            load_system('Elevator_Group_Final');
            find_system;
       switch(app.MaintenanceButton.Value)
           case 0
               app.Obstruction_SensorButtonGroup.Visible = 'off';
               app.Smoke_DetectorButtonGroup.Visible = 'off';
               app.Weight_SensorButtonGroup.Visible = 'off';
               app.RESETButton.Visible = 'off';
           case 1
               app.Obstruction_SensorButtonGroup.Visible = 'on';
               app.Smoke_DetectorButtonGroup.Visible = 'on';
               app.Weight_SensorButtonGroup.Visible = 'on';
               app.RESETButton.Visible = 'on';
       end
        end

        % Selection changed function: Obstruction_SensorButtonGroup
        function Obstruction_SensorButtonGroupSelectionChanged(app, event)
            selectedButton = app.Obstruction_SensorButtonGroup.SelectedObject;
            load_system('Elevator_Group_Final');
            find_system;
            if(selectedButton == app.ObstructionButton)
                app.ObstructLamp.Color = 'green';
                set_param('Elevator_Group_Final/Obstruction_Sensor','Value','1');
            elseif(selectedButton == app.No_ObstructionButton)
                set_param('Elevator_Group_Final/Obstruction_Sensor','Value','0');
                app.ObstructLamp.Color = 'white';
            end
        end

        % Selection changed function: Smoke_DetectorButtonGroup
        function Smoke_DetectorButtonGroupSelectionChanged(app, event)
            selectedButton = app.Smoke_DetectorButtonGroup.SelectedObject;
            load_system('Elevator_Group_Final');
            find_system;
            if(selectedButton == app.SmokeButton)
                set_param('Elevator_Group_Final/Smoke_Detector','Value','1');
                app.FireAlarms.Color = 'red';
            elseif(selectedButton == app.NosmokeButton)
                set_param('Elevator_Group_Final/Smoke_Detector','Value','0');
                app.FireAlarms.Color = 'white';
            end
        end

        % Selection changed function: Weight_SensorButtonGroup
        function Weight_SensorButtonGroupSelectionChanged(app, event)
            selectedButton = app.Weight_SensorButtonGroup.SelectedObject;
            load_system('Elevator_Group_Final');
            find_system;
            if(selectedButton == app.kgButton)
                app.WeightWarning.Color = 'white';
                set_param('Elevator_Group_Final/Weight_Sensor','Value','0')
            elseif(selectedButton == app.kgButton_2)
                app.WeightWarning.Color = 'white';
                set_param('Elevator_Group_Final/Weight_Sensor','Value','150')
            elseif(selectedButton == app.kgButton_3)
                app.WeightWarning.Color = 'blue';
                set_param('Elevator_Group_Final/Weight_Sensor','Value','300')
            elseif(selectedButton == app.kgButton_4)
                app.WeightWarning.Color = 'blue';
                set_param('Elevator_Group_Final/Weight_Sensor','Value','400')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InsideElevatorPanel = uipanel(app.UIFigure);
            app.InsideElevatorPanel.Title = 'Inside Elevator';
            app.LiftDisplay = uieditfield(app.InsideElevatorPanel, 'text');
            app.LiftDisplay.Value = 'G';
            app.LiftDirection = uieditfield(app.InsideElevatorPanel, 'text');
            app.Button = uibutton(app.InsideElevatorPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '4';
            app.Button_2 = uibutton(app.InsideElevatorPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Text = '3';
            app.Button_3 = uibutton(app.InsideElevatorPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.InsideElevatorPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Text = '1';
            app.GButton = uibutton(app.InsideElevatorPanel, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GButton.Text = 'G';
            app.OpenDoors = uibutton(app.InsideElevatorPanel, 'push');
            app.OpenDoors.ButtonPushedFcn = createCallbackFcn(app, @OpenDoorsButtonPushed, true);
            app.OpenDoors.Text = 'Open Doors';
            app.CloseDoors = uibutton(app.InsideElevatorPanel, 'push');
            app.CloseDoors.ButtonPushedFcn = createCallbackFcn(app, @CloseDoorsButtonPushed, true);
            app.CloseDoors.Text = 'Close Doors';
            app.Alarm = uibutton(app.InsideElevatorPanel, 'push');
            app.Alarm.ButtonPushedFcn = createCallbackFcn(app, @AlarmButtonPushed, true);
            app.Alarm.Text = 'Alarm';
            app.Button_5 = uibutton(app.InsideElevatorPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Text = '5';
            app.OutsideElevatorPanel = uipanel(app.UIFigure);
            app.OutsideElevatorPanel.Title = 'Outside Elevator';
            app.CallLiftButton = uibutton(app.OutsideElevatorPanel, 'push');
            app.CallLiftButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CallLiftButton.Text = 'Call Lift';
            app.FloorLiftIsAt = uieditfield(app.OutsideElevatorPanel, 'text');
            app.FloorLiftIsAt.Value = 'G';
            app.FloorEditFieldLabel = uilabel(app.OutsideElevatorPanel);
            app.FloorEditFieldLabel.Text = 'Floor';
            app.FloorLiftIsCalledFrom = uieditfield(app.OutsideElevatorPanel, 'text');
            app.FloorLiftIsCalledFrom.Value = '3';
            app.MaintenanceButton = uibutton(app.UIFigure, 'state');
            app.MaintenanceButton.ValueChangedFcn = createCallbackFcn(app, @MaintenanceButtonValueChanged, true);
            app.MaintenanceButton.Text = 'Maintenance';
            app.Obstruction_SensorButtonGroup = uibuttongroup(app.UIFigure);
            app.Obstruction_SensorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Obstruction_SensorButtonGroupSelectionChanged, true);
            app.Obstruction_SensorButtonGroup.Title = 'Obstruction_Sensor';
            app.No_ObstructionButton = uiradiobutton(app.Obstruction_SensorButtonGroup);
            app.No_ObstructionButton.Text = 'No_Obstruction';
            app.No_ObstructionButton.Value = true;
            app.ObstructionButton = uiradiobutton(app.Obstruction_SensorButtonGroup);
            app.ObstructionButton.Text = 'Obstruction';
            app.ObstructLamp = uilamp(app.Obstruction_SensorButtonGroup);
            app.Weight_SensorButtonGroup = uibuttongroup(app.UIFigure);
            app.Weight_SensorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Weight_SensorButtonGroupSelectionChanged, true);
            app.Weight_SensorButtonGroup.Title = 'Weight_Sensor';
            app.kgButton = uiradiobutton(app.Weight_SensorButtonGroup);
            app.kgButton.Text = '0kg';
            app.kgButton.Value = true;
            app.kgButton_2 = uiradiobutton(app.Weight_SensorButtonGroup);
            app.kgButton_2.Text = '150kg';
            app.kgButton_3 = uiradiobutton(app.Weight_SensorButtonGroup);
            app.kgButton_3.Text = '300kg';
            app.kgButton_4 = uiradiobutton(app.Weight_SensorButtonGroup);
            app.kgButton_4.Text = '400kg';
            app.WeightWarning = uilamp(app.Weight_SensorButtonGroup);
            app.Smoke_DetectorButtonGroup = uibuttongroup(app.UIFigure);
            app.Smoke_DetectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Smoke_DetectorButtonGroupSelectionChanged, true);
            app.Smoke_DetectorButtonGroup.Title = 'Smoke_Detector';
            app.NosmokeButton = uiradiobutton(app.Smoke_DetectorButtonGroup);
            app.NosmokeButton.Text = 'No smoke';
            app.NosmokeButton.Value = true;
            app.SmokeButton = uiradiobutton(app.Smoke_DetectorButtonGroup);
            app.SmokeButton.Text = 'Smoke';
            app.FireAlarms = uilamp(app.Smoke_DetectorButtonGroup);
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiftAppFINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end